classdef vigaSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ControlesPanel            matlab.ui.container.Panel
        VinculaoEsquerdaPanel     matlab.ui.container.Panel
        VinculaoEsquerdaDropDown  matlab.ui.control.DropDown
        MEditFieldLabel           matlab.ui.control.Label
        MEEditField               matlab.ui.control.NumericEditField
        FEditFieldLabel           matlab.ui.control.Label
        FEEditField               matlab.ui.control.NumericEditField
        VinculaoDireitaPanel      matlab.ui.container.Panel
        VinculaoDireitaDropDown   matlab.ui.control.DropDown
        MEditFieldLabel_2         matlab.ui.control.Label
        MDEditField               matlab.ui.control.NumericEditField
        FEditFieldLabel_2         matlab.ui.control.Label
        FDEditField               matlab.ui.control.NumericEditField
        Panel                     matlab.ui.container.Panel
        LEditFieldLabel           matlab.ui.control.Label
        LEditField                matlab.ui.control.NumericEditField
        ListBox                   matlab.ui.control.ListBox
        ForcaConcentradaPanel     matlab.ui.container.Panel
        FEditFieldLabel_3         matlab.ui.control.Label
        FEditField                matlab.ui.control.NumericEditField
        xEditFieldLabel           matlab.ui.control.Label
        xFConEditField            matlab.ui.control.NumericEditField
        AdicionarButton           matlab.ui.control.Button
        PlotarButton              matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        RemoverButton             matlab.ui.control.Button
        ForcaDistribuidaPanel     matlab.ui.container.Panel
        q0EditFieldLabel          matlab.ui.control.Label
        q0EditField               matlab.ui.control.NumericEditField
        xinicialEditFieldLabel    matlab.ui.control.Label
        xinicialEditField         matlab.ui.control.NumericEditField
        xfinalEditFieldLabel      matlab.ui.control.Label
        xfinalEditField           matlab.ui.control.NumericEditField
        ordemEditFieldLabel       matlab.ui.control.Label
        ordemEditField            matlab.ui.control.NumericEditField
        MomentoConcentradoPanel   matlab.ui.container.Panel
        MEditFieldLabel_3         matlab.ui.control.Label
        MEditField                matlab.ui.control.NumericEditField
        xEditField_2Label         matlab.ui.control.Label
        xMEditField               matlab.ui.control.NumericEditField
        ApoioPanel                matlab.ui.container.Panel
        xEditField_4Label         matlab.ui.control.Label
        xApoioEditField           matlab.ui.control.NumericEditField
        RotulaPanel               matlab.ui.container.Panel
        xEditField_3Label         matlab.ui.control.Label
        xRotEditField             matlab.ui.control.NumericEditField
        VyUIAxes                  matlab.ui.control.UIAxes
        MzAxes                    matlab.ui.control.UIAxes
        ThetaAxes                 matlab.ui.control.UIAxes
        vAxes                     matlab.ui.control.UIAxes
        Panel_2                   matlab.ui.container.Panel
        SimuladordeVigaGuilhermeNishinoFontebassoLabel  matlab.ui.control.Label
        Status                    matlab.ui.control.Label
    end


    properties (Access = private)
        selectedRow = 0;% Description
        Vy; % Description
        Mz;
        EIzzthetaz;
        EIzzv;
    end

    methods (Access = private)
    
        function drawTriangle(app,ax,x,dx)
            tri = [x x-dx x+dx x; -0.1 -0.4 -0.4 -0.1;];
            plot(ax,tri(1,:),tri(2,:),'LineWidth',0.5,'Color','k');
        end
        
        function drawCircle(app,ax,x,r,L)
            rectangle(ax,'Position',[x-r*L*130/490/2, -r, r*L*130/490, 2*r],'Curvature',[1,1]);
        end
        
        function drawSketch(app)
            L = app.LEditField.Value;
            cla(app.UIAxes);
            rectangle(app.UIAxes,'Position',[0, -0.1, L ,0.2]);
            axis(app.UIAxes,[-L*0.025 L*1.025 -1 1]);
            
            if app.VinculaoEsquerdaDropDown.Value == "Engastada"
                rectangle(app.UIAxes,'Position',[-L*0.025, -0.4, L*0.025 ,0.8]);
            elseif app.VinculaoEsquerdaDropDown.Value == "Apoiada"
                drawTriangle(app,app.UIAxes,0,L*0.025);
                if app.MEEditField.Value ~= 0     
                    xCenter = 0;
                    yCenter = 0; 
                    radius = 0.25;
                    theta = linspace(90, 270, 100);
                    x = radius*L*130/490/3 * cosd(theta) + xCenter; 
                    y = radius * sind(theta) + yCenter; 
                    plot(app.UIAxes,x, y,'LineWidth',0.5,'Color','k'); 
                    if app.MEEditField.Value > 0
                        line(app.UIAxes,[-0.04*L*130/490,0],[radius+0.05,radius],'LineWidth',0.5,'Color','k');                         
                    else
                        line(app.UIAxes,[-0.04*L*130/490,0],[-radius-0.05,-radius],'LineWidth',0.5,'Color','k'); 
                    end
                end
            else
                if app.FEEditField.Value ~= 0
                    line(app.UIAxes,[0,0],[0.1,0.7]);
                    if app.FEEditField.Value > 0
                        line(app.UIAxes,[-0.04*L*130/490/2,0],[0.55,0.7]);
                        line(app.UIAxes,[0.04*L*130/490/2,0],[0.55,0.7]);                          
                    else
                        line(app.UIAxes,[-0.04*L*130/490/2,0],[0.25,0.1]);
                        line(app.UIAxes,[0.04*L*130/490/2,0],[0.25,0.1]); 
                    end
                end
                if app.MEEditField.Value ~= 0     
                    xCenter = 0;
                    yCenter = 0; 
                    radius = 0.25;
                    theta = linspace(90, 270, 100);
                    x = radius*L*130/490/3 * cosd(theta) + xCenter; 
                    y = radius * sind(theta) + yCenter; 
                    plot(app.UIAxes,x, y,'LineWidth',0.5,'Color','k'); 
                    if app.MEEditField.Value > 0
                        line(app.UIAxes,[-0.04*L*130/490,0],[radius+0.05,radius],'LineWidth',0.5,'Color','k');                         
                    else
                        line(app.UIAxes,[-0.04*L*130/490,0],[-radius-0.05,-radius],'LineWidth',0.5,'Color','k'); 
                    end
                end
            end
            
            if app.VinculaoDireitaDropDown.Value == "Engastada"
                rectangle(app.UIAxes,'Position',[L, -0.4, L*0.025 ,0.8]);
            elseif app.VinculaoDireitaDropDown.Value == "Apoiada"
                drawTriangle(app,app.UIAxes,L,L*0.025);
                if app.MDEditField.Value ~= 0     
                    xCenter = L;
                    yCenter = 0; 
                    radius = 0.25;
                    theta = [linspace(270, 360, 100),linspace(0, 90, 100)];

                    x = radius*L*130/490/3 * cosd(theta) + xCenter; 
                    y = radius * sind(theta) + yCenter; 
                    plot(app.UIAxes,x, y,'LineWidth',0.5,'Color','k'); 
                    if app.MDEditField.Value > 0
                        line(app.UIAxes,[L+0.04*L*130/490,L],[radius+0.05,radius],'LineWidth',0.5,'Color','k');                         
                    else
                        line(app.UIAxes,[L+0.04*L*130/490,L],[-radius-0.05,-radius],'LineWidth',0.5,'Color','k'); 
                    end
                end
            else
                if app.FDEditField.Value ~= 0
                    line(app.UIAxes,[L,L],[0.1,0.7]);
                    if app.FDEditField.Value > 0
                        line(app.UIAxes,[L-0.04*L*130/490/2,L],[0.25,0.1]);
                        line(app.UIAxes,[L+0.04*L*130/490/2,L],[0.25,0.1]);                           
                    else
                        line(app.UIAxes,[L-0.04*L*130/490/2,L],[0.55,0.7]);
                        line(app.UIAxes,[L+0.04*L*130/490/2,L],[0.55,0.7]);
                    end
                end
            end   
            
            data = get(app.UITable,'Data');
            for i = 1:size(data,1)
               if data{i,1} == "Apoio"
                   drawTriangle(app,app.UIAxes,data{i,3},L*0.025);
               end                  
               if data{i,1} == "Rótula"
                   drawCircle(app,app.UIAxes,data{i,3},0.1,L);
               end
               if data{i,1} == "Força Concentrada"
                    if data{i,2} ~= 0
                        line(app.UIAxes,[data{i,3},data{i,3}],[0.1,0.7]);
                        if data{i,2} > 0
                            line(app.UIAxes,[data{i,3}-0.04*L*130/490/2,data{i,3}],[0.55,0.7]);
                            line(app.UIAxes,[data{i,3}+0.04*L*130/490/2,data{i,3}],[0.55,0.7]);                          
                        else
                            line(app.UIAxes,[data{i,3}-0.04*L*130/490/2,data{i,3}],[0.25,0.1]);
                            line(app.UIAxes,[data{i,3}+0.04*L*130/490/2,data{i,3}],[0.25,0.1]);
                        end
                    end
               end
               if data{i,1} == "Momento Concentrado"
                   drawArch(app,data{i,3},data{i,2},L);
               end
            end
        end
        
        function drawArch(app,pos,value,L)
            xCenter = pos;
            yCenter = 0; 
            radius = 0.25;
            theta = linspace(0, 180, 100);

            x = radius*L*130/490/3 * cosd(theta) + xCenter; 
            y = radius * sind(theta) + yCenter; 
            plot(app.UIAxes,x, y,'LineWidth',0.5,'Color','k');
            pos0 = radius*L*130/490/3 * cosd(0) + xCenter;
            pos180 = radius*L*130/490/3 * cosd(180) + xCenter;
            if value < 0                
                line(app.UIAxes,[pos180,pos180],[0.2,0],'LineWidth',0.5,'Color','k');                         
            else

                line(app.UIAxes,[pos0,pos0],[0.2,0],'LineWidth',0.5,'Color','k');
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.YAxis.Visible = 'off';
            app.UIAxes.NextPlot = 'add';
        end

        % Button pushed function: AdicionarButton
        function AdicionarButtonPushed(app, event)
            value = app.ListBox.Value;
            
            if app.LEditField.Value == 0     
                uialert(app.UIFigure,'Escolha um comprimento em metros para a Viga','Erro');
            else
                oldData = get(app.UITable,'Data');
                if value == "Força Concentrada"
                    if app.LEditField.Value < app.xFConEditField.Value     
                        uialert(app.UIFigure,'A posição da força concentrada precisa estar entre os limites da Viga','Erro');
                    else
                        newRow = {'Força Concentrada', app.FEditField.Value, app.xFConEditField.Value, '-', '-'};
                        newData = [oldData; newRow];
                        set(app.UITable,'data',newData);
                    end
                elseif value == "Momento Concentrado"
                    if app.LEditField.Value < app.xMEditField.Value     
                        uialert(app.UIFigure,'A posição do momento concentrad0 precisa estar entre os limites da Viga','Erro');
                    else
                        newRow = {'Momento Concentrado', app.MEditField.Value, app.xMEditField.Value, '-', '-'}; 
                        newData = [oldData; newRow];
                        set(app.UITable,'data',newData);
                    end                   
                elseif value == "Rótula"
                    if app.LEditField.Value < app.xRotEditField.Value     
                        uialert(app.UIFigure,'A posição da rótula precisa estar entre os limites da Viga','Erro');
                    else
                        newRow = {'Rótula', '-', app.xRotEditField.Value, '-', '-'};
                        newData = [oldData; newRow];
                        set(app.UITable,'data',newData);
                    end                    
                elseif value == "Apoio"
                    if app.LEditField.Value < app.xApoioEditField.Value   
                        uialert(app.UIFigure,'A posição do apoio precisa estar entre os limites da Viga','Erro');
                    else
                        newRow = {'Apoio', '-', app.xApoioEditField.Value, '-', '-'};
                        newData = [oldData; newRow];
                        set(app.UITable,'data',newData);
                    end                
                else %Força Distribuida
                    if app.LEditField.Value < app.xfinalEditField.Value     
                        uialert(app.UIFigure,'A posição final da força distribuida precisa estar entre os limites da Viga','Erro');
                    else
                        newRow = {'Força Distribuída', app.q0EditField.Value, app.xinicialEditField.Value, app.xfinalEditField.Value, app.ordemEditField.Value};
                        newData = [oldData; newRow];
                        set(app.UITable,'data',newData);
                    end
                end
                drawSketch(app);
            end
        end

        % Value changed function: FDEditField
        function FDEditFieldValueChanged(app, event)
            value = app.FDEditField.Value;
            drawSketch(app);
        end

        % Value changed function: FEEditField
        function FEEditFieldValueChanged(app, event)
            value = app.FEEditField.Value;
            drawSketch(app);
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            value = app.LEditField.Value;
            if value > 0
                drawSketch(app);
            end 
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            if value == "Força Concentrada"
               app.ForcaConcentradaPanel.Visible = 'on';
               app.MomentoConcentradoPanel.Visible = 'off'; 
               app.RotulaPanel.Visible = 'off'; 
               app.ApoioPanel.Visible = 'off'; 
               app.ForcaDistribuidaPanel.Visible = 'off';
            elseif value == "Momento Concentrado"
               app.ForcaConcentradaPanel.Visible = 'off';
               app.MomentoConcentradoPanel.Visible = 'on'; 
               app.RotulaPanel.Visible = 'off'; 
               app.ApoioPanel.Visible = 'off'; 
               app.ForcaDistribuidaPanel.Visible = 'off';
            elseif value == "Rótula"
               app.ForcaConcentradaPanel.Visible = 'off';
               app.MomentoConcentradoPanel.Visible = 'off'; 
               app.RotulaPanel.Visible = 'on'; 
               app.ApoioPanel.Visible = 'off'; 
               app.ForcaDistribuidaPanel.Visible = 'off';
            elseif value == "Apoio"
               app.ForcaConcentradaPanel.Visible = 'off';
               app.MomentoConcentradoPanel.Visible = 'off'; 
               app.RotulaPanel.Visible = 'off'; 
               app.ApoioPanel.Visible = 'on'; 
               app.ForcaDistribuidaPanel.Visible = 'off';
            else
               app.ForcaConcentradaPanel.Visible = 'off';
               app.MomentoConcentradoPanel.Visible = 'off'; 
               app.RotulaPanel.Visible = 'off'; 
               app.ApoioPanel.Visible = 'off'; 
               app.ForcaDistribuidaPanel.Visible = 'on';
            end
        end

        % Value changed function: MDEditField
        function MDEditFieldValueChanged(app, event)
            value = app.MDEditField.Value;
            drawSketch(app);
        end

        % Value changed function: MEEditField
        function MEEditFieldValueChanged(app, event)
            value = app.MEEditField.Value;
            drawSketch(app);
        end

        % Button pushed function: PlotarButton
        function PlotarButtonPushed(app, event)
            if app.LEditField.Value == 0     
                    uialert(app.UIFigure,'Escolha um comprimento em metros para a Viga','Erro');
            else
                app.Status.Text = 'Carregando';
                    
                [app.Vy, app.Mz, app.EIzzthetaz, app.EIzzv] = ...
                                   simulador_viga(get(app.UITable,'Data'), ...
                                   app.LEditField.Value, ...
                                   app.VinculaoEsquerdaDropDown.Value, ...
                                   app.VinculaoDireitaDropDown.Value, ...
                                   app.MEEditField.Value, ...
                                   app.FEEditField.Value, ...
                                   app.MDEditField.Value, ...
                                   app.FDEditField.Value);
                interval = [0, app.LEditField.Value];
                
                cla(app.VyUIAxes);
                cla(app.MzAxes);
                cla(app.ThetaAxes);
                cla(app.vAxes);
                
            %                 app.VyUIAxes.YAxis.TickLabelFormat = '%.1f';
            %                 app.MzAxes.YAxis.TickLabelFormat = '%.1f';
            %                 app.MzAxes.YAxis.Exponent = 2;
            %                 app.ThetaAxes.YAxis.TickLabelFormat = '%.1f';
            %                 app.ThetaAxes.YAxis.Exponent = 4;
            %                 app.vAxes.YAxis.TickLabelFormat = '%.1f';
            %                 app.vAxes.YAxis.Exponent = 5;
                try
                    h = fplot(app.VyUIAxes,app.Vy,interval);
                    x = get(h, 'XData');
                    y = get(h, 'YData');
                    area(app.VyUIAxes,x,y,'FaceColor',[0 0.25 0.25],'FaceAlpha',0.2);
                    
                    h = fplot(app.MzAxes,app.Mz,interval);
                    x = get(h, 'XData');
                    y = get(h, 'YData');
                    area(app.MzAxes,x,y,'FaceColor',[0 0.25 0.25],'FaceAlpha',0.2);
                    
                    h = fplot(app.ThetaAxes,app.EIzzthetaz,interval);
                    x = get(h, 'XData');
                    y = get(h, 'YData');
                    area(app.ThetaAxes,x,y,'FaceColor',[0 0.25 0.25],'FaceAlpha',0.2);
                    
                    h = fplot(app.vAxes,app.EIzzv,interval);
                    x = get(h, 'XData');
                    y = get(h, 'YData');
                    area(app.vAxes,x,y,'FaceColor',[0 0.25 0.25],'FaceAlpha',0.2);
                    
                    line(app.VyUIAxes,interval,[0,0],'LineWidth',1,'Color','k');
                    line(app.MzAxes,interval,[0,0],'LineWidth',1,'Color','k');
                    line(app.ThetaAxes,interval,[0,0],'LineWidth',1,'Color','k');
                    line(app.vAxes,interval,[0,0],'LineWidth',1,'Color','k');
                    
                    data = get(app.UITable,'Data');
                    for i = 1:size(data,1)
                        line(app.VyUIAxes,[data{i,3},data{i,3}],app.VyUIAxes.YLim,'LineWidth',0.5,'Color','k','LineStyle','--');
                        line(app.MzAxes,[data{i,3},data{i,3}],app.MzAxes.YLim,'LineWidth',0.5,'Color','k','LineStyle','--');
                        line(app.ThetaAxes,[data{i,3},data{i,3}],app.ThetaAxes.YLim,'LineWidth',0.5,'Color','k','LineStyle','--');
                        line(app.vAxes,[data{i,3},data{i,3}],app.vAxes.YLim,'LineWidth',0.5,'Color','k','LineStyle','--');
                    end
                    
                    drawSketch(app);    
                catch
                    uialert(app.UIFigure,'A Viga é hipostática!','Erro');
                end
                app.Status.Text = '';
            end
        end

        % Button pushed function: RemoverButton
        function RemoverButtonPushed(app, event)
            if app.selectedRow ~= 0
                oldData = get(app.UITable,'Data');
                oldData(app.selectedRow,:) = [];
                set(app.UITable,'data',oldData);
                if(size(oldData,1) == 0)
                   app.selectedRow = 0;
                elseif(size(oldData,1) < app.selectedRow)
                   app.selectedRow = 0;
                end
                drawSketch(app);
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.selectedRow = event.Indices(1);
        end

        % Value changed function: VinculaoDireitaDropDown
        function VinculaoDireitaDropDownValueChanged(app, event)
            value = app.VinculaoDireitaDropDown.Value;
            if value == "Engastada"
                app.MDEditField.Enable = 'off';
                app.FDEditField.Enable = 'off';
            elseif value == "Apoiada"
                app.MDEditField.Enable = 'on';
                app.FDEditField.Enable = 'off';
            else %Livre
                app.MDEditField.Enable = 'on';
                app.FDEditField.Enable = 'on';
            end
            drawSketch(app);
        end

        % Value changed function: VinculaoEsquerdaDropDown
        function VinculaoEsquerdaDropDownValueChanged(app, event)
            value = app.VinculaoEsquerdaDropDown.Value;
            if value == "Engastada"
                app.MEEditField.Enable = 'off';
                app.FEEditField.Enable = 'off';
            elseif value == "Apoiada"
                app.MEEditField.Enable = 'on';
                app.FEEditField.Enable = 'off';
            else %Livre
                app.MEEditField.Enable = 'on';
                app.FEEditField.Enable = 'on';
            end
            drawSketch(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Esboço')
            ylabel(app.UIAxes, ' _ ')
            app.ControlesPanel = uipanel(app.UIFigure);
            app.ControlesPanel.Title = 'Controles';
            app.VinculaoEsquerdaPanel = uipanel(app.ControlesPanel);
            app.VinculaoEsquerdaPanel.Title = 'Vinculação Esquerda';
            app.VinculaoEsquerdaDropDown = uidropdown(app.VinculaoEsquerdaPanel);
            app.VinculaoEsquerdaDropDown.Items = {'Engastada', 'Apoiada', 'Livre'};
            app.VinculaoEsquerdaDropDown.ValueChangedFcn = createCallbackFcn(app, @VinculaoEsquerdaDropDownValueChanged, true);
            app.VinculaoEsquerdaDropDown.Value = 'Livre';
            app.MEditFieldLabel = uilabel(app.VinculaoEsquerdaPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEEditField = uieditfield(app.VinculaoEsquerdaPanel, 'numeric');
            app.MEEditField.ValueChangedFcn = createCallbackFcn(app, @MEEditFieldValueChanged, true);
            app.FEditFieldLabel = uilabel(app.VinculaoEsquerdaPanel);
            app.FEditFieldLabel.Text = 'F';
            app.FEEditField = uieditfield(app.VinculaoEsquerdaPanel, 'numeric');
            app.FEEditField.ValueChangedFcn = createCallbackFcn(app, @FEEditFieldValueChanged, true);
            app.VinculaoDireitaPanel = uipanel(app.ControlesPanel);
            app.VinculaoDireitaPanel.Title = 'Vinculação Direita';
            app.VinculaoDireitaDropDown = uidropdown(app.VinculaoDireitaPanel);
            app.VinculaoDireitaDropDown.Items = {'Engastada', 'Apoiada', 'Livre'};
            app.VinculaoDireitaDropDown.ValueChangedFcn = createCallbackFcn(app, @VinculaoDireitaDropDownValueChanged, true);
            app.VinculaoDireitaDropDown.Value = 'Livre';
            app.MEditFieldLabel_2 = uilabel(app.VinculaoDireitaPanel);
            app.MEditFieldLabel_2.Text = 'M';
            app.MDEditField = uieditfield(app.VinculaoDireitaPanel, 'numeric');
            app.MDEditField.ValueChangedFcn = createCallbackFcn(app, @MDEditFieldValueChanged, true);
            app.FEditFieldLabel_2 = uilabel(app.VinculaoDireitaPanel);
            app.FEditFieldLabel_2.Text = 'F';
            app.FDEditField = uieditfield(app.VinculaoDireitaPanel, 'numeric');
            app.FDEditField.ValueChangedFcn = createCallbackFcn(app, @FDEditFieldValueChanged, true);
            app.Panel = uipanel(app.ControlesPanel);
            app.LEditFieldLabel = uilabel(app.Panel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.Panel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Limits = [0 Inf];
            app.ListBox = uilistbox(app.ControlesPanel);
            app.ListBox.Items = {'Força Concentrada', 'Momento Concentrado', 'Força Distribuída', 'Rótula', 'Apoio'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Rótula';
            app.ForcaConcentradaPanel = uipanel(app.ControlesPanel);
            app.ForcaConcentradaPanel.Title = 'Força Concentrada';
            app.FEditFieldLabel_3 = uilabel(app.ForcaConcentradaPanel);
            app.FEditFieldLabel_3.Text = 'F';
            app.FEditField = uieditfield(app.ForcaConcentradaPanel, 'numeric');
            app.xEditFieldLabel = uilabel(app.ForcaConcentradaPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xFConEditField = uieditfield(app.ForcaConcentradaPanel, 'numeric');
            app.xFConEditField.Limits = [0 Inf];
            app.AdicionarButton = uibutton(app.ControlesPanel, 'push');
            app.AdicionarButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarButtonPushed, true);
            app.AdicionarButton.Text = 'Adicionar';
            app.PlotarButton = uibutton(app.ControlesPanel, 'push');
            app.PlotarButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarButtonPushed, true);
            app.PlotarButton.Text = 'Plotar';
            app.UITable = uitable(app.ControlesPanel);
            app.UITable.ColumnName = {'Tipo'; 'F/M/q0'; 'Posição'; 'Final'; 'Ordem'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.RemoverButton = uibutton(app.ControlesPanel, 'push');
            app.RemoverButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverButtonPushed, true);
            app.RemoverButton.Text = 'Remover';
            app.ForcaDistribuidaPanel = uipanel(app.ControlesPanel);
            app.ForcaDistribuidaPanel.Title = 'Força Distribuida';
            app.q0EditFieldLabel = uilabel(app.ForcaDistribuidaPanel);
            app.q0EditFieldLabel.Text = 'q0';
            app.q0EditField = uieditfield(app.ForcaDistribuidaPanel, 'numeric');
            app.xinicialEditFieldLabel = uilabel(app.ForcaDistribuidaPanel);
            app.xinicialEditFieldLabel.Text = 'x inicial';
            app.xinicialEditField = uieditfield(app.ForcaDistribuidaPanel, 'numeric');
            app.xinicialEditField.Limits = [0 Inf];
            app.xfinalEditFieldLabel = uilabel(app.ForcaDistribuidaPanel);
            app.xfinalEditFieldLabel.Text = 'x final';
            app.xfinalEditField = uieditfield(app.ForcaDistribuidaPanel, 'numeric');
            app.xfinalEditField.Limits = [0 Inf];
            app.ordemEditFieldLabel = uilabel(app.ForcaDistribuidaPanel);
            app.ordemEditFieldLabel.Text = 'ordem';
            app.ordemEditField = uieditfield(app.ForcaDistribuidaPanel, 'numeric');
            app.ordemEditField.Limits = [0 Inf];
            app.MomentoConcentradoPanel = uipanel(app.ControlesPanel);
            app.MomentoConcentradoPanel.Title = 'Momento Concentrado';
            app.MEditFieldLabel_3 = uilabel(app.MomentoConcentradoPanel);
            app.MEditFieldLabel_3.Text = 'M';
            app.MEditField = uieditfield(app.MomentoConcentradoPanel, 'numeric');
            app.xEditField_2Label = uilabel(app.MomentoConcentradoPanel);
            app.xEditField_2Label.Text = 'x';
            app.xMEditField = uieditfield(app.MomentoConcentradoPanel, 'numeric');
            app.xMEditField.Limits = [0 Inf];
            app.ApoioPanel = uipanel(app.ControlesPanel);
            app.ApoioPanel.Title = 'Apoio';
            app.xEditField_4Label = uilabel(app.ApoioPanel);
            app.xEditField_4Label.Text = 'x';
            app.xApoioEditField = uieditfield(app.ApoioPanel, 'numeric');
            app.xApoioEditField.Limits = [0 Inf];
            app.RotulaPanel = uipanel(app.ControlesPanel);
            app.RotulaPanel.Title = 'Rótula';
            app.xEditField_3Label = uilabel(app.RotulaPanel);
            app.xEditField_3Label.Text = 'x';
            app.xRotEditField = uieditfield(app.RotulaPanel, 'numeric');
            app.xRotEditField.Limits = [0 Inf];
            app.VyUIAxes = uiaxes(app.UIFigure);
            ylabel(app.VyUIAxes, 'V_Y(x)')
            app.MzAxes = uiaxes(app.UIFigure);
            ylabel(app.MzAxes, 'M_Z(x)')
            app.ThetaAxes = uiaxes(app.UIFigure);
            ylabel(app.ThetaAxes, 'EI_{ZZ}\theta_Z(x)')
            app.vAxes = uiaxes(app.UIFigure);
            ylabel(app.vAxes, 'EI_{ZZ}v(x)')
            app.Panel_2 = uipanel(app.UIFigure);
            app.SimuladordeVigaGuilhermeNishinoFontebassoLabel = uilabel(app.Panel_2);
            app.SimuladordeVigaGuilhermeNishinoFontebassoLabel.Text = 'Simulador de Viga - Guilherme Nishino Fontebasso';
            app.Status = uilabel(app.UIFigure);
            app.Status.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = vigaSolver

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end